classdef timestable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TimesTablesUIFigure      matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout4              matlab.ui.container.GridLayout
        CloseButton              matlab.ui.control.Button
        OKButton                 matlab.ui.control.Button
        GridLayout3              matlab.ui.container.GridLayout
        EditField                matlab.ui.control.NumericEditField
        WhatisLabel              matlab.ui.control.Label
        Question                 matlab.ui.control.Label
        GridLayout2              matlab.ui.container.GridLayout
        Score                    matlab.ui.control.Label
        ScoreLabel               matlab.ui.control.Label
        TimesTableDropDown       matlab.ui.control.DropDown
        TimesTableDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Game % Game object
    end
    
    methods (Access = public)
        
        function askNewQuestion(app)
            app.Game.setNewQuestion;
            app.updateUI;
        end
    
        function updateUI(app)
            % Create and set a string that looks like
            % (current times table) X (current question)            
            string = sprintf('%d X %d', ...
                app.Game.CurrentTimesTable, app.Game.CurrentQuestion);            
            app.Question.Text = string;
            
            % Clear current answer:
            app.EditField.Value = 0;
            
            % Set score:
            app.Score.Text = num2str(app.Game.NumberCorrectAnswers);
        end    
    
        function setAppPostion(app)
            % Move the UI to the centre of the screen:
            screensize = get(0, 'screensize');
            uiFigurePosition = app.TimesTablesUIFigure.Position;
            centreX = floor((screensize(3)-screensize(1))/2);
            centreY = floor((screensize(4)-screensize(2))/2);
            app.TimesTablesUIFigure.Position = ...
                [centreX-0.5*uiFigurePosition(3) ...
                centreY-0.5*uiFigurePosition(4) ...
                uiFigurePosition(3) uiFigurePosition(4)];
        end
    end
    
    methods (Access = private)
        
        function checkAnswer(app)
            % game.
            value = app.EditField.Value;
            if app.Game.isAnswerCorrect(value)
                app.Game.incrementScore;
                app.askNewQuestion;
            else
                app.askNewQuestion;
                app.updateUI;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function newGame(app)
            app.setAppPostion;
            app.Game = timesTableGame;
            app.TimesTableDropDown.Value = app.Game.CurrentTimesTable;
            app.askNewQuestion;            
        end

        % Button pushed function: CloseButton
        function userQuit(app, event)
            app.delete();
        end

        % Button pushed function: OKButton
        function okButtonPressed(app, event)
            checkAnswer(app);
        end

        % Value changed function: TimesTableDropDown
        function timestableChanged(app, event)
            value = app.TimesTableDropDown.Value;
            app.Game.setNewTimesTable(value);
            app.updateUI;
        end

        % Window key press function: TimesTablesUIFigure
        function enterKeyPressed(app, event)
            if event.Key == "return"
                checkAnswer(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TimesTablesUIFigure = uifigure('Visible', 'off');
            app.TimesTablesUIFigure.Name = 'Times Tables';
            app.TimesTablesUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @enterKeyPressed, true);
            app.GridLayout = uigridlayout(app.TimesTablesUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TimesTableDropDownLabel = uilabel(app.GridLayout2);
            app.TimesTableDropDownLabel.Text = 'Times Table';
            app.TimesTableDropDown = uidropdown(app.GridLayout2);
            app.TimesTableDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.TimesTableDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.TimesTableDropDown.ValueChangedFcn = createCallbackFcn(app, @timestableChanged, true);
            app.TimesTableDropDown.Value = 5;
            app.ScoreLabel = uilabel(app.GridLayout2);
            app.ScoreLabel.Text = 'Score';
            app.Score = uilabel(app.GridLayout2);
            app.Score.Text = '0';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Question = uilabel(app.GridLayout3);
            app.Question.Text = '';
            app.WhatisLabel = uilabel(app.GridLayout3);
            app.WhatisLabel.Text = 'What is';
            app.EditField = uieditfield(app.GridLayout3, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.RoundFractionalValues = 'on';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.OKButton = uibutton(app.GridLayout4, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPressed, true);
            app.OKButton.Text = 'OK';
            app.CloseButton = uibutton(app.GridLayout4, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @userQuit, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.TimesTablesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timestable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TimesTablesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @newGame)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TimesTablesUIFigure)
        end
    end
end